classdef two_colors < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Spinner_Rmin          matlab.ui.control.Spinner
        Spinner_Rmax          matlab.ui.control.Spinner
        RLabel                matlab.ui.control.Label
        Spinner_Gmin          matlab.ui.control.Spinner
        Spinner_Gmax          matlab.ui.control.Spinner
        GLabel                matlab.ui.control.Label
        Spinner_Bmin          matlab.ui.control.Spinner
        Spinner_Bmax          matlab.ui.control.Spinner
        BLabel                matlab.ui.control.Label
        Button_prev           matlab.ui.control.Button
        Button_next           matlab.ui.control.Button
        RdSpinnerLabel        matlab.ui.control.Label
        RdSpinner             matlab.ui.control.Spinner
        RhSpinnerLabel        matlab.ui.control.Label
        RhSpinner             matlab.ui.control.Spinner
        RtSpinnerLabel        matlab.ui.control.Label
        RtSpinner             matlab.ui.control.Spinner
        GdSpinnerLabel        matlab.ui.control.Label
        GdSpinner             matlab.ui.control.Spinner
        GhSpinnerLabel        matlab.ui.control.Label
        GhSpinner             matlab.ui.control.Spinner
        GtSpinnerLabel        matlab.ui.control.Label
        GtSpinner             matlab.ui.control.Spinner
        numfilesLabel         matlab.ui.control.Label
        showpointsCheckBox    matlab.ui.control.CheckBox
        showmergeCheckBox     matlab.ui.control.CheckBox
        filenameLabel         matlab.ui.control.Label
        newpointButton        matlab.ui.control.Button
        filenumEditField      matlab.ui.control.NumericEditField
        justresultsCheckBox   matlab.ui.control.CheckBox
        initpercLabel         matlab.ui.control.Label
        initpercLabel_2       matlab.ui.control.Label
        RdSpinner_init        matlab.ui.control.Spinner
        RhSpinner_init        matlab.ui.control.Spinner
        RtSpinner_init        matlab.ui.control.Spinner
        GdSpinner_init        matlab.ui.control.Spinner
        GhSpinner_init        matlab.ui.control.Spinner
        GtSpinner_init        matlab.ui.control.Spinner
        initpercSpinnerLabel  matlab.ui.control.Label
        initpercSpinner       matlab.ui.control.Spinner
        TredSpinnerLabel      matlab.ui.control.Label
        TredSpinner           matlab.ui.control.Spinner
        TgreenSpinnerLabel    matlab.ui.control.Label
        TgreenSpinner         matlab.ui.control.Spinner
        TredSpinner_init      matlab.ui.control.Spinner
        initpercLabel_3       matlab.ui.control.Label
        TgreenSpinner_init    matlab.ui.control.Spinner
        initpercLabel_4       matlab.ui.control.Label
        UIAxes_Gright         matlab.ui.control.UIAxes
        UIAxes_Rleft          matlab.ui.control.UIAxes
        UIAxes_Gdown          matlab.ui.control.UIAxes
        UIAxes_Rdown          matlab.ui.control.UIAxes
        UIAxes_Gmain          matlab.ui.control.UIAxes
        UIAxes_Rmain          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        files
        data
        points_R 
        points_G
        points_values_R 
        points_values_G
        
        binaryResuslts_R
        binaryResuslts_G
        
        rImg_main
        gImg_main
        bImg_main
        
        rImg_down
        gImg_down
        bImg_down
        
        rImg_right
        gImg_right
        bImg_right
        
        
        rImg_left
        gImg_left
        bImg_left
        
        
        img_main_r
        img_main_g
        img_down_r
        img_down_g
        img_left_r
        img_right_g


        points_main_r_1
        points_main_g_1
        points_down_r_1
        points_down_g_1
        points_left_r_1
        points_right_g_1
        
        points_main_r_2
        points_main_g_2
        points_down_r_2
        points_down_g_2
        points_left_r_2
        points_right_g_2
        
        
        points_main_r_3
        points_main_g_3
        points_down_r_3
        points_down_g_3
        points_left_r_3
        points_right_g_3
        
        
    end
    
    methods (Access = public)
        
        function reload_file(app)
            app.data = load(app.files(app.filenumEditField.Value).name);
            app.filenameLabel.Text = app.files(app.filenumEditField.Value).name;
        end
        
        function show_img_R(app)
            hold(app.UIAxes_Rmain, 'off')
            color=cat(3,...
                mat2gray(app.rImg_main,[app.Spinner_Rmin.Value   app.Spinner_Rmax.Value]),...
                zeros(size(app.rImg_main),'like',app.rImg_main),...
                mat2gray(app.bImg_main,[app.Spinner_Bmin.Value  app.Spinner_Bmax.Value]));
            app.img_main_r=imshow(color,'Parent',app.UIAxes_Rmain);
            title(app.UIAxes_Rmain,'')
            ylabel(app.UIAxes_Rmain,'')
            xlabel(app.UIAxes_Rmain,'')
            
  
            hold(app.UIAxes_Rdown, 'off')
            color=cat(3,...
                mat2gray(app.rImg_down,[app.Spinner_Rmin.Value   app.Spinner_Rmax.Value]),...
                zeros(size(app.rImg_down),'like',app.rImg_down),...
                mat2gray(app.bImg_down,[app.Spinner_Bmin.Value  app.Spinner_Bmax.Value]));
            app.img_down_r=imshow(color,'Parent',app.UIAxes_Rdown);
            title(app.UIAxes_Rdown,'')
            ylabel(app.UIAxes_Rdown,'')
            xlabel(app.UIAxes_Rdown,'')
            
             hold(app.UIAxes_Rleft, 'off')
            color=cat(3,...
                mat2gray(app.rImg_left,[app.Spinner_Rmin.Value   app.Spinner_Rmax.Value]),...
                zeros(size(app.rImg_left),'like',app.rImg_left),...
                mat2gray(app.bImg_left,[app.Spinner_Bmin.Value  app.Spinner_Bmax.Value]));
            app.img_left_r=imshow(color,'Parent',app.UIAxes_Rleft);
            title(app.UIAxes_Rleft,'')
            ylabel(app.UIAxes_Rleft,'')
            xlabel(app.UIAxes_Rleft,'')
            
        end
        
        function show_img_G(app)
            
            
            hold(app.UIAxes_Gmain, 'off')
            color=cat(3,...
                zeros(size(app.gImg_main),'like',app.gImg_main),...
                mat2gray(app.gImg_main,[app.Spinner_Gmin.Value   app.Spinner_Gmax.Value]),...
                mat2gray(app.bImg_main,[app.Spinner_Bmin.Value  app.Spinner_Bmax.Value]));
            app.img_main_g=imshow(color,'Parent',app.UIAxes_Gmain);
            title(app.UIAxes_Gmain,'')
            ylabel(app.UIAxes_Gmain,'')
            xlabel(app.UIAxes_Gmain,'')
            
            
            hold(app.UIAxes_Gdown, 'off')
            color=cat(3,...
                zeros(size(app.gImg_down),'like',app.gImg_down),...
                mat2gray(app.gImg_down,[app.Spinner_Gmin.Value   app.Spinner_Gmax.Value]),...
                mat2gray(app.bImg_down,[app.Spinner_Bmin.Value  app.Spinner_Bmax.Value]));
            app.img_down_g=imshow(color,'Parent',app.UIAxes_Gdown);
            title(app.UIAxes_Gdown,'')
            ylabel(app.UIAxes_Gdown,'')
            xlabel(app.UIAxes_Gdown,'')
            
            hold(app.UIAxes_Gright, 'off')
            color=cat(3,...
                zeros(size(app.gImg_right),'like',app.gImg_right),...
                mat2gray(app.gImg_right,[app.Spinner_Gmin.Value   app.Spinner_Gmax.Value]),...
                mat2gray(app.bImg_right,[app.Spinner_Bmin.Value  app.Spinner_Bmax.Value]));
            app.img_right_g=imshow(color,'Parent',app.UIAxes_Gright);
            title(app.UIAxes_Gright,'')
            ylabel(app.UIAxes_Gright,'')
            xlabel(app.UIAxes_Gright,'')
            
        end  
        
        function show_imgs(app)
            app.show_img_R()
            app.show_img_G()
        end
        
        function show_points(app)
            app.show_points_R()
            app.show_points_G()
        end
        
        
        function show_points_R(app)
            
            hold(app.UIAxes_Rmain, 'on')
            if ~isempty(app.points_main_r_1)
                delete(app.points_main_r_1);delete(app.points_main_r_2);delete(app.points_main_r_3);
            end
            app.points_main_r_1=plot(app.UIAxes_Rmain,app.points_R(:,1), app.points_R(:,2), 'b*','MarkerSize',3);
            app.points_main_r_2=plot(app.UIAxes_Rmain,app.points_R(app.binaryResuslts_R,1), app.points_R(app.binaryResuslts_R,2), 'ro','MarkerSize',3);
            app.points_main_r_3=plot(app.UIAxes_Rmain,app.points_R(app.binaryResuslts_R,1), app.points_R(app.binaryResuslts_R,2), 'g*','MarkerSize',3);


            hold(app.UIAxes_Rdown, 'on')
            if ~isempty(app.points_down_r_1)
                delete(app.points_down_r_1);delete(app.points_down_r_2);delete(app.points_down_r_3);
            end
            app.points_down_r_1=plot(app.UIAxes_Rdown,app.points_R(:,1), app.points_R(:,3), 'b*','MarkerSize',3);
            app.points_down_r_2=plot(app.UIAxes_Rdown,app.points_R(app.binaryResuslts_R,1), app.points_R(app.binaryResuslts_R,3), 'ro','MarkerSize',3);
            app.points_down_r_3=plot(app.UIAxes_Rdown,app.points_R(app.binaryResuslts_R,1), app.points_R(app.binaryResuslts_R,3), 'g*','MarkerSize',3);


            hold(app.UIAxes_Rleft, 'on')
            if ~isempty(app.points_left_r_1)
                delete(app.points_left_r_1);delete(app.points_left_r_2);delete(app.points_left_r_3);
            end
            app.points_left_r_1=plot(app.UIAxes_Rleft,app.points_R(:,3), app.points_R(:,2), 'b*','MarkerSize',3);
            app.points_left_r_2=plot(app.UIAxes_Rleft,app.points_R(app.binaryResuslts_R,3), app.points_R(app.binaryResuslts_R,2), 'ro','MarkerSize',3);
            app.points_left_r_3=plot(app.UIAxes_Rleft,app.points_R(app.binaryResuslts_R,3), app.points_R(app.binaryResuslts_R,2), 'g*','MarkerSize',3);
            
            
        end
        
        
        function show_points_G(app)
            
            hold(app.UIAxes_Gmain, 'on')
            if ~isempty(app.points_main_g_1)
                delete(app.points_main_g_1);delete(app.points_main_g_2);delete(app.points_main_g_3);
            end
            app.points_main_g_1=plot(app.UIAxes_Gmain,app.points_G(:,1), app.points_G(:,2), 'b*','MarkerSize',3);
            app.points_main_g_2=plot(app.UIAxes_Gmain,app.points_G(app.binaryResuslts_G,1), app.points_G(app.binaryResuslts_G,2), 'ro','MarkerSize',3);
            app.points_main_g_3=plot(app.UIAxes_Gmain,app.points_G(app.binaryResuslts_G,1), app.points_G(app.binaryResuslts_G,2), 'g*','MarkerSize',3);


            hold(app.UIAxes_Gdown, 'on')
            if ~isempty(app.points_down_g_1)
                delete(app.points_down_g_1);delete(app.points_down_g_2);delete(app.points_down_g_3);
            end
            app.points_down_g_1=plot(app.UIAxes_Gdown,app.points_G(:,1), app.points_G(:,3), 'b*','MarkerSize',3);
            app.points_down_g_2=plot(app.UIAxes_Gdown,app.points_G(app.binaryResuslts_G,1), app.points_G(app.binaryResuslts_G,3), 'ro','MarkerSize',3);
            app.points_down_g_3=plot(app.UIAxes_Gdown,app.points_G(app.binaryResuslts_G,1), app.points_G(app.binaryResuslts_G,3), 'g*','MarkerSize',3);
            
            
            hold(app.UIAxes_Gright, 'on')
            if ~isempty(app.points_right_g_1)
                delete(app.points_right_g_1);delete(app.points_right_g_2);delete(app.points_right_g_3);
            end
            tmp = size(app.data.img_crop,3);
            app.points_right_g_1=plot(app.UIAxes_Gright,tmp-app.points_G(:,3), app.points_G(:,2), 'b*','MarkerSize',3);
            app.points_right_g_2=plot(app.UIAxes_Gright,tmp-app.points_G(app.binaryResuslts_G,3), app.points_G(app.binaryResuslts_G,2), 'ro','MarkerSize',3);
            app.points_right_g_3=plot(app.UIAxes_Gright,tmp-app.points_G(app.binaryResuslts_G,3), app.points_G(app.binaryResuslts_G,2), 'g*','MarkerSize',3);
            
            
        end


        function get_segmentations(app)
            [app.points_R,app.points_values_R] = robust_3d_maxima_detector(...
                app.data.img_crop(:,:,:,1),app.data.mask_crop,[app.RdSpinner.Value app.RdSpinner.Value round(app.RdSpinner.Value/3)],...
                app.RhSpinner.Value,...
                app.RtSpinner.Value);
            
            [app.points_G,app.points_values_G] = robust_3d_maxima_detector(...
                app.data.img_crop(:,:,:,2),app.data.mask_crop,[app.GdSpinner.Value app.GdSpinner.Value round(app.GdSpinner.Value/3)],...
                app.GhSpinner.Value,...
                app.GtSpinner.Value);
            
            app.binaryResuslts_R = app.points_values_R>app.TredSpinner.Value;
            app.binaryResuslts_G = app.points_values_G>app.TgreenSpinner.Value;
            
            
        end
        
        function init_show_ranges(app)
            
            app.Spinner_Rmin.Value =  prctile(app.data.img_crop(:,:,:,1),app.initpercSpinner.Value,'all');
            app.Spinner_Rmax.Value =  prctile(app.data.img_crop(:,:,:,1),100-app.initpercSpinner.Value,'all');
            
            app.Spinner_Gmin.Value =  prctile(app.data.img_crop(:,:,:,2),app.initpercSpinner.Value,'all');
            app.Spinner_Gmax.Value =  prctile(app.data.img_crop(:,:,:,2),100-app.initpercSpinner.Value,'all');
            
            app.Spinner_Bmin.Value =  prctile(app.data.img_crop(:,:,:,3),app.initpercSpinner.Value,'all');
            app.Spinner_Bmax.Value =  prctile(app.data.img_crop(:,:,:,3),100-app.initpercSpinner.Value,'all');
            
        end
        
        function project_data(app)
            
             
            app.rImg_main=max(app.data.img_crop(:,:,:,1),[],3);
            app.gImg_main=max(app.data.img_crop(:,:,:,2),[],3);
            app.bImg_main=max(app.data.img_crop(:,:,:,3),[],3);
            
            app.rImg_left=squeeze(max(app.data.img_crop(:,:,:,1),[],2));
            app.gImg_left=squeeze(max(app.data.img_crop(:,:,:,2),[],2));
            app.bImg_left=squeeze(max(app.data.img_crop(:,:,:,3),[],2));
            
            app.rImg_right=squeeze(max(app.data.img_crop(:,:,:,1),[],2));
            app.gImg_right=squeeze(max(app.data.img_crop(:,:,:,2),[],2));
            app.bImg_right=squeeze(max(app.data.img_crop(:,:,:,3),[],2));
            
            app.rImg_right=app.rImg_right(:,end:-1:1);
            app.gImg_right=app.gImg_right(:,end:-1:1);
            app.bImg_right=app.bImg_right(:,end:-1:1);
            
            app.rImg_down=squeeze(max(app.data.img_crop(:,:,:,1),[],1))';
            app.gImg_down=squeeze(max(app.data.img_crop(:,:,:,2),[],1))';
            app.bImg_down=squeeze(max(app.data.img_crop(:,:,:,3),[],1))';
            
            
        end
        
        function init_next(app)
            
            
            app.reload_file()


            app.RdSpinner.Value = app.RdSpinner_init.Value;
            tmp = app.data.img_crop(:,:,:,1);
            app.RhSpinner.Value = prctile(tmp(app.data.mask_crop),app.RtSpinner_init.Value)-prctile(tmp(app.data.mask_crop),app.RhSpinner_init.Value);
            app.RtSpinner.Value = prctile(tmp(app.data.mask_crop),app.RtSpinner_init.Value);
            app.TredSpinner.Value = prctile(tmp(app.data.mask_crop),app.TredSpinner_init.Value);
            
            
            app.GdSpinner.Value = app.GdSpinner_init.Value;
            tmp = app.data.img_crop(:,:,:,2);
            app.GhSpinner.Value = prctile(tmp(app.data.mask_crop),app.GtSpinner_init.Value)-prctile(tmp(app.data.mask_crop),app.GhSpinner_init.Value);
            app.GtSpinner.Value = prctile(tmp(app.data.mask_crop),app.GtSpinner_init.Value);
            app.TgreenSpinner.Value = prctile(tmp(app.data.mask_crop),app.TgreenSpinner_init.Value);
            
            
            app.init_show_ranges()
            app.project_data()
            app.show_imgs()
            
            if ~isfile(replace(app.files(app.filenumEditField.Value).name,'cell','result'))
                app.get_segmentations()
            else
                app.load_results(app)
            end
            
            
            app.show_points_R()
            app.show_points_G()
            
        end
        
        function save_results(app)
            name = replace(app.files(app.filenumEditField.Value).name,'cell','result');
            
            points_R = app.points_R ;
            points_G = app.points_G;
            points_values_R = app.points_values_R ;
            points_values_G = app.points_values_G;
            
            save(name,'points_R','points_G','points_values_R','points_values_G')
        end
        function load_results(app)
            name = replace(app.files(app.filenumEditField.Value).name,'cell','result');
            res = load(name);
            
            app.points_R = res.points_R ;
            app.points_G = apresp.points_G;
            app.points_values_R = res.points_values_R ;
            app.points_values_G = res.points_values_G;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            addpath('utils')

%             path = uigetdir();
            path = 'G:\Sdílené disky\martin_data\NHDF_preprocess_test\NHDF_8h PI';
            app.files = subdir([path '/cell*.mat']);


            app.numfilesLabel.Text =  ['/' num2str(length(app.files))];
            app.filenumEditField.Limits = [0 length(app.files)];
            
            app.filenumEditField.Value = 1;
            for k = 1:length(app.files)
                if ~isfile(replace(app.files(k).name,'cell','result'))
                    app.filenumEditField.Value = k;
                    break
                end
            end
            
            app.init_next()

        end

        % Button pushed function: Button_next
        function Button_nextPushed(app, event)
            app.save_results()
            app.filenumEditField.Value = app.filenumEditField.Value+1;
            app.init_next()
        end

        % Button pushed function: Button_prev
        function Button_prevPushed(app, event)
            app.save_results()
            app.filenumEditField.Value = app.filenumEditField.Value-1;
            app.init_next()
        end

        % Value changed function: filenumEditField
        function filenumEditFieldValueChanged(app, event)
            app.filenumEditField.Value = event.PreviousValue;
            app.save_results()
            app.filenumEditField.Value = event.Value;
            app.init_next()
        end

        % Value changed function: Spinner_Rmin
        function Spinner_RminValueChanged(app, event)
            app.show_img_R()
            app.show_points_R()
            
        end

        % Value changed function: Spinner_Rmax
        function Spinner_RmaxValueChanged(app, event)
            app.show_img_R()
            app.show_points_R()
        end

        % Value changed function: Spinner_Gmin
        function Spinner_GminValueChanged(app, event)
            app.show_img_G()
            app.show_points_G()
        end

        % Value changed function: Spinner_Gmax
        function Spinner_GmaxValueChanged(app, event)
            app.show_img_G()
            app.show_points_G()
        end

        % Value changed function: Spinner_Bmin
        function Spinner_BminValueChanged(app, event)
            app.show_imgs()
            app.show_points()
        end

        % Value changed function: Spinner_Bmax
        function Spinner_BmaxValueChanged(app, event)
            app.show_imgs()
            app.show_points()
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            try 
                 if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Rmain)
                     position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                     position=[position(1,1),position(1,2)];
                    
                     [min_dist,closest]=min((app.points_R(:,1)-position(1)).^2+(app.points_R(:,2)-position(2)).^2);
                     if min_dist<30^2
                         if strcmp(app.UIFigure.SelectionType,'normal')
                             tmp=1;
                         elseif strcmp(app.UIFigure.SelectionType,'alt')
                             tmp=0;                              
                         end
                     end
                     app.binaryResuslts_R(closest)=tmp;
                     app.show_points_R()
                 end
                 
                 
                 if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Rdown)
                     position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                     position=[position(1,1),position(1,2)];
                    
                     [min_dist,closest]=min((app.points_R(:,1)-position(1)).^2+(app.points_R(:,3)-position(2)).^2);
                     if min_dist<30^2
                         if strcmp(app.UIFigure.SelectionType,'normal')
                             tmp=1;
                         elseif strcmp(app.UIFigure.SelectionType,'alt')
                             tmp=0;                              
                         end
                     end
                     app.binaryResuslts_R(closest)=tmp;
                     app.show_points_R()
                 end
                 
                 if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Rleft)
                     position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                     position=[position(1,1),position(1,2)];
                    
                     [min_dist,closest]=min((app.points_R(:,3)-position(1)).^2+(app.points_R(:,2)-position(2)).^2);
                     if min_dist<30^2
                         if strcmp(app.UIFigure.SelectionType,'normal')
                             tmp=1;
                         elseif strcmp(app.UIFigure.SelectionType,'alt')
                             tmp=0;                              
                         end
                     end
                     app.binaryResuslts_R(closest)=tmp;
                     app.show_points_R()
                 end


                 if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Gmain)
                     position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                     position=[position(1,1),position(1,2)];
                    
                     [min_dist,closest]=min((app.points_G(:,1)-position(1)).^2+(app.points_G(:,2)-position(2)).^2);
                     if min_dist<30^2
                         if strcmp(app.UIFigure.SelectionType,'normal')
                             tmp=1;
                         elseif strcmp(app.UIFigure.SelectionType,'alt')
                             tmp=0;                              
                         end
                     end
                     app.binaryResuslts_G(closest)=tmp;
                     app.show_points_G()
                 end
                 
                 
                 if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Gdown)
                     position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                     position=[position(1,1),position(1,2)];
                    
                     [min_dist,closest]=min((app.points_G(:,1)-position(1)).^2+(app.points_G(:,3)-position(2)).^2);
                     if min_dist<30^2
                         if strcmp(app.UIFigure.SelectionType,'normal')
                             tmp=1;
                         elseif strcmp(app.UIFigure.SelectionType,'alt')
                             tmp=0;                              
                         end
                     end
                     app.binaryResuslts_G(closest)=tmp;
                     app.show_points_G()
                 end
                 
                 if isequal(event.Source.CurrentObject.Parent,app.UIAxes_Gright)
                     position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
                     position=[position(1,1),position(1,2)];
                    
                     tmp = size(app.data.img_crop,3);
                     [min_dist,closest]=min((tmp-app.points_G(:,3)-position(1)).^2+(app.points_G(:,2)-position(2)).^2);
                     if min_dist<30^2
                         if strcmp(app.UIFigure.SelectionType,'normal')
                             tmp=1;
                         elseif strcmp(app.UIFigure.SelectionType,'alt')
                             tmp=0;                              
                         end
                     end
                     app.binaryResuslts_G(closest)=tmp;
                     app.show_points_G()
                 end


            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.Spinner_Rmin = uispinner(app.UIFigure);
            app.Spinner_Rmin.ValueChangedFcn = createCallbackFcn(app, @Spinner_RminValueChanged, true);
            app.Spinner_Rmax = uispinner(app.UIFigure);
            app.Spinner_Rmax.ValueChangedFcn = createCallbackFcn(app, @Spinner_RmaxValueChanged, true);
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = 'R';
            app.Spinner_Gmin = uispinner(app.UIFigure);
            app.Spinner_Gmin.ValueChangedFcn = createCallbackFcn(app, @Spinner_GminValueChanged, true);
            app.Spinner_Gmax = uispinner(app.UIFigure);
            app.Spinner_Gmax.ValueChangedFcn = createCallbackFcn(app, @Spinner_GmaxValueChanged, true);
            app.GLabel = uilabel(app.UIFigure);
            app.GLabel.Text = 'G';
            app.Spinner_Bmin = uispinner(app.UIFigure);
            app.Spinner_Bmin.ValueChangedFcn = createCallbackFcn(app, @Spinner_BminValueChanged, true);
            app.Spinner_Bmax = uispinner(app.UIFigure);
            app.Spinner_Bmax.ValueChangedFcn = createCallbackFcn(app, @Spinner_BmaxValueChanged, true);
            app.BLabel = uilabel(app.UIFigure);
            app.BLabel.Text = 'B';
            app.Button_prev = uibutton(app.UIFigure, 'push');
            app.Button_prev.ButtonPushedFcn = createCallbackFcn(app, @Button_prevPushed, true);
            app.Button_prev.Text = '<';
            app.Button_next = uibutton(app.UIFigure, 'push');
            app.Button_next.ButtonPushedFcn = createCallbackFcn(app, @Button_nextPushed, true);
            app.Button_next.Text = '>';
            app.RdSpinnerLabel = uilabel(app.UIFigure);
            app.RdSpinnerLabel.Text = 'R d';
            app.RdSpinner = uispinner(app.UIFigure);
            app.RhSpinnerLabel = uilabel(app.UIFigure);
            app.RhSpinnerLabel.Text = 'R h';
            app.RhSpinner = uispinner(app.UIFigure);
            app.RtSpinnerLabel = uilabel(app.UIFigure);
            app.RtSpinnerLabel.Text = 'R t';
            app.RtSpinner = uispinner(app.UIFigure);
            app.GdSpinnerLabel = uilabel(app.UIFigure);
            app.GdSpinnerLabel.Text = 'G d';
            app.GdSpinner = uispinner(app.UIFigure);
            app.GhSpinnerLabel = uilabel(app.UIFigure);
            app.GhSpinnerLabel.Text = 'G h';
            app.GhSpinner = uispinner(app.UIFigure);
            app.GtSpinnerLabel = uilabel(app.UIFigure);
            app.GtSpinnerLabel.Text = 'G t';
            app.GtSpinner = uispinner(app.UIFigure);
            app.numfilesLabel = uilabel(app.UIFigure);
            app.numfilesLabel.Text = '/100';
            app.showpointsCheckBox = uicheckbox(app.UIFigure);
            app.showpointsCheckBox.Text = 'show points';
            app.showpointsCheckBox.Value = true;
            app.showmergeCheckBox = uicheckbox(app.UIFigure);
            app.showmergeCheckBox.Text = 'show merge';
            app.filenameLabel = uilabel(app.UIFigure);
            app.filenameLabel.Text = 'filename';
            app.newpointButton = uibutton(app.UIFigure, 'push');
            app.newpointButton.Text = 'new point';
            app.filenumEditField = uieditfield(app.UIFigure, 'numeric');
            app.filenumEditField.ValueChangedFcn = createCallbackFcn(app, @filenumEditFieldValueChanged, true);
            app.justresultsCheckBox = uicheckbox(app.UIFigure);
            app.justresultsCheckBox.Text = 'just results';
            app.initpercLabel = uilabel(app.UIFigure);
            app.initpercLabel.Text = 'init-perc';
            app.initpercLabel_2 = uilabel(app.UIFigure);
            app.initpercLabel_2.Text = 'init-perc';
            app.RdSpinner_init = uispinner(app.UIFigure);
            app.RdSpinner_init.Limits = [1 Inf];
            app.RdSpinner_init.Value = 9;
            app.RhSpinner_init = uispinner(app.UIFigure);
            app.RhSpinner_init.Limits = [1 99];
            app.RhSpinner_init.Value = 80;
            app.RtSpinner_init = uispinner(app.UIFigure);
            app.RtSpinner_init.Step = 0.5;
            app.RtSpinner_init.Limits = [0 100];
            app.RtSpinner_init.Value = 98;
            app.GdSpinner_init = uispinner(app.UIFigure);
            app.GdSpinner_init.Limits = [1 Inf];
            app.GdSpinner_init.Value = 9;
            app.GhSpinner_init = uispinner(app.UIFigure);
            app.GhSpinner_init.Limits = [0 100];
            app.GhSpinner_init.Value = 8;
            app.GtSpinner_init = uispinner(app.UIFigure);
            app.GtSpinner_init.Step = 0.5;
            app.GtSpinner_init.Limits = [0 100];
            app.GtSpinner_init.Value = 98;
            app.initpercSpinnerLabel = uilabel(app.UIFigure);
            app.initpercSpinnerLabel.Text = 'init-perc';
            app.initpercSpinner = uispinner(app.UIFigure);
            app.initpercSpinner.Step = 0.01;
            app.initpercSpinner.Limits = [0 100];
            app.initpercSpinner.Value = 0.1;
            app.TredSpinnerLabel = uilabel(app.UIFigure);
            app.TredSpinnerLabel.Text = 'T red';
            app.TredSpinner = uispinner(app.UIFigure);
            app.TgreenSpinnerLabel = uilabel(app.UIFigure);
            app.TgreenSpinnerLabel.Text = 'T green';
            app.TgreenSpinner = uispinner(app.UIFigure);
            app.TredSpinner_init = uispinner(app.UIFigure);
            app.TredSpinner_init.Value = 99;
            app.initpercLabel_3 = uilabel(app.UIFigure);
            app.initpercLabel_3.Text = 'init-perc';
            app.TgreenSpinner_init = uispinner(app.UIFigure);
            app.TgreenSpinner_init.Value = 99;
            app.initpercLabel_4 = uilabel(app.UIFigure);
            app.initpercLabel_4.Text = 'init-perc';
            app.UIAxes_Gright = uiaxes(app.UIFigure);
            title(app.UIAxes_Gright, 'Title')
            xlabel(app.UIAxes_Gright, 'X')
            ylabel(app.UIAxes_Gright, 'Y')
            zlabel(app.UIAxes_Gright, 'Z')
            app.UIAxes_Rleft = uiaxes(app.UIFigure);
            title(app.UIAxes_Rleft, 'Title')
            xlabel(app.UIAxes_Rleft, 'X')
            ylabel(app.UIAxes_Rleft, 'Y')
            zlabel(app.UIAxes_Rleft, 'Z')
            app.UIAxes_Gdown = uiaxes(app.UIFigure);
            title(app.UIAxes_Gdown, 'Title')
            xlabel(app.UIAxes_Gdown, 'X')
            ylabel(app.UIAxes_Gdown, 'Y')
            zlabel(app.UIAxes_Gdown, 'Z')
            app.UIAxes_Rdown = uiaxes(app.UIFigure);
            title(app.UIAxes_Rdown, 'Title')
            xlabel(app.UIAxes_Rdown, 'X')
            ylabel(app.UIAxes_Rdown, 'Y')
            zlabel(app.UIAxes_Rdown, 'Z')
            app.UIAxes_Gmain = uiaxes(app.UIFigure);
            title(app.UIAxes_Gmain, 'Title')
            xlabel(app.UIAxes_Gmain, 'X')
            ylabel(app.UIAxes_Gmain, 'Y')
            zlabel(app.UIAxes_Gmain, 'Z')
            app.UIAxes_Rmain = uiaxes(app.UIFigure);
            title(app.UIAxes_Rmain, 'Title')
            xlabel(app.UIAxes_Rmain, 'X')
            ylabel(app.UIAxes_Rmain, 'Y')
            zlabel(app.UIAxes_Rmain, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = two_colors

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end